classdef CovidVisualizationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CovidVisualizationAppUIFigure  matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        CountryListBoxLabel            matlab.ui.control.Label
        CountryListBox                 matlab.ui.control.ListBox
        StateListBoxLabel              matlab.ui.control.Label
        StateListBox                   matlab.ui.control.ListBox
        MovingAverageSpinnerLabel      matlab.ui.control.Label
        MovingAverageSpinner           matlab.ui.control.Spinner
        OptionsSwitchLabel             matlab.ui.control.Label
        OptionsSwitch                  matlab.ui.control.Switch
        PlotKnobLabel                  matlab.ui.control.Label
        PlotKnob                       matlab.ui.control.DiscreteKnob
    end

    
    properties (Access = private)
        NewCases  % Database with all the new cases
        NewDeaths  % Database with all the new deaths
         
        CountriesAndStates % Cell of countries and States
        Dates  % Cell of dates
        
        % Country  % Selected Country
        % State  % Selected State
        
    end
    
    methods (Access = private)
        
        function plotData(app)
            country = app.CountryListBox.Value;
            state = app.StateListBox.Value;
            opt = app.OptionsSwitch.Value;
            num_date = datenum(app.Dates);
            
            % Editting the x label values
            xlim(app.UIAxes, [num_date(1) num_date(end)])
            datetick(app.UIAxes, 'x', 'dd/mm')
            xlim(app.UIAxes, 'manual')  % Necessary?????
            
            hold(app.UIAxes, "on")  
            if app.PlotKnob.Value == "Both"
                % Changing the title
                full_title = sprintf('New cases and deaths of COVID-19 for %s (%s)', country, state);
                title(app.UIAxes, full_title)
                
                if opt == "Daily"
                    % Selecting the New Cases database for the given country and state
                    da_new_cases = app.NewCases(strcmp(app.NewCases(:, 1), country),  2:end);
                    da_new_cases = da_new_cases(strcmp(da_new_cases(:, 1), state), 2:end);
                
                    % Selecting the New Deaths database for the given country and state
                    da_new_deaths = app.NewDeaths(strcmp(app.NewDeaths(:, 1), country),  2:end);
                    da_new_deaths = da_new_deaths(strcmp(da_new_deaths(:, 1), state), 2:end);
                
                else  % i.e., opt == "Culmulative"
                    % NEED TO DEVELOP
                    
                end
                
                % Editing the left Y axis and label and ploting the New Cases data
                yyaxis(app.UIAxes, "left")
                ylabel(app.UIAxes, 'New cases')
                bar(app.UIAxes, datenum(app.Dates), cell2mat(da_new_cases))
                
                % Editing the rigth Y axis and label and ploting the New Deaths data
                yyaxis(app.UIAxes, "right")
                ylabel(app.UIAxes, 'New deaths')
                plot(app.UIAxes, datenum(app.Dates), cell2mat(da_new_deaths), 'r')
            
            elseif app.PlotKnob.Value == "Cases"
                % Changing the title
                full_title = sprintf('New cases of COVID-19 for %s (%s)', country, state);
                title(app.UIAxes, full_title)
                
                if opt == "Daily"
                    % Selecting the New Cases database for the given country and state
                    da_new_cases = app.NewCases(strcmp(app.NewCases(:, 1), country),  2:end);
                    da_new_cases = da_new_cases(strcmp(da_new_cases(:, 1), state), 2:end);
                    
                else  % i.e., opt == "Culmulative"
                    % NEED TO DEVELOP
                    
                end
                
                % Editing the left Y axis and label and ploting the New Cases data
                yyaxis(app.UIAxes, "left")
                ylabel(app.UIAxes, 'New cases')
                bar(app.UIAxes, datenum(app.Dates), cell2mat(da_new_cases))
            
            else  % i.e. app.PlotKnob.Value == "Deaths"
                % Changing the title
                full_title = sprintf('New deaths of COVID-19 for %s (%s)', country, state);
                title(app.UIAxes, full_title)
                    
                if opt == "Daily"
                    % Selecting the New Deaths database for the given country and state
                    da_new_deaths = app.NewDeaths(strcmp(app.NewDeaths(:, 1), country),  2:end);
                    da_new_deaths = da_new_deaths(strcmp(da_new_deaths(:, 1), state), 2:end);
                    
                else  % i.e., opt == "Culmulative"
                    % NEED TO DEVELOP
                    
                end
                
                % Editing the rigth Y axis and label and ploting the New Deaths data
                yyaxis(app.UIAxes, "right")
                ylabel(app.UIAxes, 'New deaths')
                plot(app.UIAxes, datenum(app.Dates), cell2mat(da_new_deaths), 'r')
            
            end
            
            hold(app.UIAxes, "off")   
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load covid_data.mat covid_data;
            
            da = covid_data;  % Usable data go from 2:end, to 3:end
            
            % Modifying to add 'Global':
            [rows, columns] = size(da);
            new_line = ['Global', 'All', cell(1, columns-2)];
            da = [da(1, :); new_line; da(2:end, :)];
            
            % Usefull relations:
            values = da(1, :);
            countries_and_states = da(2:end, 1:2);
            
            % Preallocating Memory:
            app.NewCases = cell(rows + 1, columns);  % The + 1 to consider the Global (added before)
            app.NewDeaths = cell(rows + 1, columns);
            
            % Adding the values, countries and states to the other databases
            app.NewCases(1, :) = values;
            app.NewDeaths(1, :) = values;
            app.NewCases(2:end, 1:2) = countries_and_states;
            app.NewDeaths(2:end, 1:2) = countries_and_states;
            
            % Separating the databases and calculating the global values
            for jj = 3:columns
                acum_case = 0;  % Acumulator --> Will be used to calculate the global cases and deaths
                acum_deaths = 0;
                for ii = 3:rows
                    acum_case = acum_case + da{ii, jj}(1);
                    acum_deaths = acum_deaths + da{ii, jj}(2);
                    
                    app.NewCases{ii, jj} = da{ii, jj}(1);
                    app.NewDeaths{ii, jj} = da{ii, jj}(2);
                end
                app.NewCases{2, jj} = acum_case;
                app.NewDeaths{2, jj} = acum_deaths;
            end
            
            % Modifying to use 'All' instead of []
            da(strcmp(da(:, 2), ""), 2) = {'All'};
            app.NewCases(strcmp(app.NewCases(:, 2), ""), 2) = {'All'};
            app.NewDeaths(strcmp(app.NewDeaths(:, 2), ""), 2) = {'All'};
            
            % Setting the properties
            app.Dates = da(1, 3:end);
            app.CountriesAndStates = da(2:end, 1:2);
       
            % Editing the lists
            app.CountryListBox.Items = unique(da(2:end, 1), 'stable');
            app.CountryListBox.Value = 'Global';
            app.StateListBox.Items = {'All'};  % This will be changed latter
            app.StateListBox.Value = 'All';
            
            app.plotData()
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            country = app.CountryListBox.Value;
            
            app.StateListBox.Items = app.CountriesAndStates(strcmp(app.CountriesAndStates(:, 1), country), 2);
            app.StateListBox.Value = 'All';
        end

        % Value changed function: OptionsSwitch
        function OptionsSwitchValueChanged(app, event)
            value = app.OptionsSwitch.Value;
            
            if value == "Culmulative"
                disp("Culmulative Button is on!")
            else  % i.e. value == "Daily" 
                disp("Daily Button is on!")
            end
        end

        % Value changed function: PlotKnob
        function PlotKnobValueChanged(app, event)
            value = app.PlotKnob.Value;
            
            if value == "Both"
                app.plotData();
            elseif value == "Cases"
                app.plotData();
            else  % i.e., value = "Death"
                app.plotData();
            end
        end

        % Value changed function: MovingAverageSpinner
        function MovingAverageSpinnerValueChanged(app, event)
            % value = app.MovingAverageSpinner.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CovidVisualizationAppUIFigure = uifigure('Visible', 'off');
            app.CovidVisualizationAppUIFigure.Name = 'Covid Visualization App';
            app.UIAxes = uiaxes(app.CovidVisualizationAppUIFigure);
            title(app.UIAxes, 'Loading...')
            xlabel(app.UIAxes, 'Date')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.CountryListBoxLabel = uilabel(app.CovidVisualizationAppUIFigure);
            app.CountryListBoxLabel.Text = 'Country';
            app.CountryListBox = uilistbox(app.CovidVisualizationAppUIFigure);
            app.CountryListBox.Items = {'Loading...', ''};
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.CountryListBox.Value = {};
            app.StateListBoxLabel = uilabel(app.CovidVisualizationAppUIFigure);
            app.StateListBoxLabel.Text = 'State';
            app.StateListBox = uilistbox(app.CovidVisualizationAppUIFigure);
            app.StateListBox.Items = {'Loading...', ''};
            app.StateListBox.Value = {};
            app.MovingAverageSpinnerLabel = uilabel(app.CovidVisualizationAppUIFigure);
            app.MovingAverageSpinnerLabel.Text = 'Moving Average';
            app.MovingAverageSpinner = uispinner(app.CovidVisualizationAppUIFigure);
            app.MovingAverageSpinner.Limits = [1 15];
            app.MovingAverageSpinner.ValueChangedFcn = createCallbackFcn(app, @MovingAverageSpinnerValueChanged, true);
            app.MovingAverageSpinner.Value = 1;
            app.OptionsSwitchLabel = uilabel(app.CovidVisualizationAppUIFigure);
            app.OptionsSwitchLabel.Text = 'Options';
            app.OptionsSwitch = uiswitch(app.CovidVisualizationAppUIFigure, 'slider');
            app.OptionsSwitch.Items = {'Culmulative', 'Daily'};
            app.OptionsSwitch.ValueChangedFcn = createCallbackFcn(app, @OptionsSwitchValueChanged, true);
            app.OptionsSwitch.Value = 'Daily';
            app.PlotKnobLabel = uilabel(app.CovidVisualizationAppUIFigure);
            app.PlotKnobLabel.Text = 'Plot';
            app.PlotKnob = uiknob(app.CovidVisualizationAppUIFigure, 'discrete');
            app.PlotKnob.Items = {'Cases', 'Both', 'Deaths'};
            app.PlotKnob.ValueChangedFcn = createCallbackFcn(app, @PlotKnobValueChanged, true);
            app.PlotKnob.Value = 'Both';
            % Show the figure after all components are created
            app.CovidVisualizationAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CovidVisualizationApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CovidVisualizationAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CovidVisualizationAppUIFigure)
        end
    end
end